classdef ColorLocator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorLocatorUIFigure        matlab.ui.Figure
        CameraURLEditField          matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
        CameraURLEditFieldLabel     matlab.ui.control.Label
        CaptureButton               matlab.ui.control.Button
        ScanButton                  matlab.ui.control.Button
        EncodeButton                matlab.ui.control.Button
        SendGcodeButton             matlab.ui.control.Button
        COMMPortEditFieldLabel      matlab.ui.control.Label
        COMMPortEditField           matlab.ui.control.EditField
        GcodeSendFinishedLampLabel  matlab.ui.control.Label
        GcodeSendFinishedLamp       matlab.ui.control.Lamp
        EncodedDataTextArea         matlab.ui.control.TextArea
        EncodedDataLabel            matlab.ui.control.Label
        AutoButton                  matlab.ui.control.Button
        CameraViewLabel             matlab.ui.control.Label
    end


    properties (Access = public)
        %Some useful variables
        URL                     % Camera URL
        COMM_PORT               %Communication port of Arduino
        IPcam_snap              %Image from iPhone
        pixel_location          %Results from NN Scan
        str_results             %Holds Gcode and location of objects
        Theta1                  %NN Weight 1
        Theta2                  %NN Weight 2
    end

    methods (Access = private)
    
        function pixel_location= APPpicScan(app,pic,Theta1,Theta2)
            % APPPICSCAN will take image 'picName' and scan through it to identify GREEN
            % and RED objects. It will use a 20x20 window to scan through the image
            
            picd = double(pic);                            %Converts to double
            
            %Resize to dimensions that is divisable by 20
            resize_picd = imresize(picd,[760 1020]);
            
            %Some constants and intializing
            results = zeros(122,163);
            vertical_low = -19;
            a =0;
            
            %Horizontal = Columns; Vertical = Rows
            for m = 1:38 %Contorls scanning across columns (Left to right)
                horizontal_low = -19; %Reset the the horizontal scan to left side
                vertical_low = vertical_low + 20;
                vertical_high = vertical_low + 19;
                for n = 1:51 %Controls scanning across rows (Top to bottom)
                    %Creates dimensions for window
                    horizontal_low = horizontal_low + 20;
                    horizontal_high = horizontal_low+19;
                    
                    %Window creation
                    window = resize_picd(vertical_low:vertical_high,...
                        horizontal_low:horizontal_high,:);
                    
                    %Extract data from RGB matricies
                    x1 = reshape(window(:,:,1),1,400);
                    x2 = reshape(window(:,:,2),1,400);
                    x3 = reshape(window(:,:,3),1,400);
                    X=[x1 x2 x3];   %Roll into a single row vector
                    X = (X-127)/255; %Feature scaling
                    
                    %Predict the color of the primary color of the window using
                    %trainned weights
                    results(m,n) = predict(Theta1,Theta2, X);
                    
                    %If the prediction outputs RED or GREEN display and store data
                    %Do not include results outside of the following dimensions
                    if horizontal_high < 700 && horizontal_high > 170 && vertical_high < 680 && vertical_high > 140
                        %Draw a box where the current window is located at
                        if(results(m,n) == 1 || results(m,n) == 3)
                            line(app.UIAxes,[horizontal_low,horizontal_low],[vertical_low,vertical_high],'LineWidth',3);
                            line(app.UIAxes,[horizontal_high,horizontal_high],[vertical_low,vertical_high],'LineWidth',3);
                            line(app.UIAxes,[horizontal_low,horizontal_high],[vertical_low,vertical_low],'LineWidth',3);
                            line(app.UIAxes,[horizontal_low,horizontal_high],[vertical_high,vertical_high],'LineWidth',3);
                            
                            a = a+1;    %Increments the row of pixel_location for a new result to be saved
                        
                            pixel_location(a,:) = [vertical_low vertical_high...
                                horizontal_low horizontal_high results(m,n)];       %Save data
                        end
                    end
               end
            end
        end

        function APPsendGcode(app,GCodeCell,PORT)
            %APPSENDGCODE   sends generated Gcode to the connected Arduino.
            %
            %       Tested only with Arduino UNO.
            %       GCodeCell = Of type CELL that contains Gcode to be sent
            %       PORT = Serial port that Arduino is connected to
            
            app.GcodeSendFinishedLamp.Enable = 'off';       %LAMP indicates if Gcode is done sending
            cellSize = size(GCodeCell,1);                   %Find size of Gcode to be sent
            
            arduino = serial(PORT, 'BaudRate',115200);      %Arduino object with 115200 Baud Rate
            set(arduino,'TimeOut',30);                      %If MATALAB does not recieve data from Arduino in 30 seconds
                                                            %then stop serial commuincation.
                                                            %Change if need more time to send data or if too slow
                                                            
            fprintf('Initiating Arduino Serial Connection...')  
            fopen(arduino);                                     %Open Serial communication

            fscanf(arduino)                                     %Print GRBL startup text
            fscanf(arduino)
            fscanf(arduino)
            homeCell = {'$H'};                                  %Home system to prevent alarms
            fprintf(arduino,homeCell{1});                       %Display Gcode sent
            confirm = fgetl(arduino);                           %Recieve status of Arduino
            fprintf('%s \t %s',homeCell{1},confirm);            %Display status of Arduino
            fprintf('Press any key to continue...')             %User has to begin sending
            pause;
            
            %Read Gcode and Send
            for n = 1:cellSize                                  
                fprintf(arduino,GCodeCell{n});                  %Display Gcode sent        
                confirm = fgetl(arduino);                       %Recieve status of Arduino
                fprintf('%s \t %s',GCodeCell{n},confirm);       %Display status
            end
            
            app.GcodeSendFinishedLamp.Enable = 'on';            %Indicate job is done
            fclose(arduino);                                    %Close Arduino port
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Load NN weights at startup
            Weights = load('testedWeights.mat');
            app.Theta1 = Weights.Theta1;
            app.Theta2 = Weights.Theta2;
        end

        % Value changed function: CameraURLEditField
        function CameraURLEditFieldValueChanged(app, event)
            %Set camera URL
            value = app.CameraURLEditField.Value;
            app.CameraURLEditField.Value = value;
            app.URL = value;
        end

        % Value changed function: COMMPortEditField
        function COMMPortEditFieldValueChanged(app, event)
            %Set Arduino COM port
            value = app.COMMPortEditField.Value;
            app.COMMPortEditField.Value = value;
            app.COMM_PORT = value;
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
%            pic = load('grid.mat');                              %Example picture for debugging
%            app.IPcam_snap = pic.grid;
            app.IPcam_snap = imageCapture(app.URL);           %Save image from iPhone
            image(app.IPcam_snap,'Parent',app.UIAxes)
            
            %Print lines for centering picture to grid lines
            line(app.UIAxes,[176 176],[0 760])
            line(app.UIAxes,[233 233],[0 760])
            line(app.UIAxes,[290 290],[0 760])
            line(app.UIAxes,[348 348],[0 760])
            line(app.UIAxes,[402 402],[0 760])
            line(app.UIAxes,[460 460],[0 760])
            line(app.UIAxes,[515 515],[0 760])
            line(app.UIAxes,[572 572],[0 760])
            line(app.UIAxes,[627 627],[0 760])
            line(app.UIAxes,[686 686],[0 760])
            line(app.UIAxes,[0 1020],[159 159])
            line(app.UIAxes,[0 1020],[231 231])
            line(app.UIAxes,[0 1020],[305 305])
            line(app.UIAxes,[0 1020],[385 385])
            line(app.UIAxes,[0 1020],[453 453])
            line(app.UIAxes,[0 1020],[527 527])
            line(app.UIAxes,[0 1020],[601 601])
            line(app.UIAxes,[0 1020],[675 675])
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
           %Scan picture for Red and Green Objects using weights
           app.pixel_location = APPpicScan(app,app.IPcam_snap,app.Theta1,app.Theta2);
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            %Find where objects are on the grid and assign Gcode
            app.str_results = encodePixel(app.pixel_location);
            pixel2Gcode(app.str_results);
            
            %Formats encoded results to displat in UI
            data = char(app.str_results);
            formatedData = [data(:,:,1),data(:,:,2),data(:,:,3),data(:,:,4),data(:,:,5)];
            for n = 1:size(app.str_results,1)
                newData{n} = formatedData(n,:);
            end
            
            app.EncodedDataTextArea.Value = newData;
        end

        % Button pushed function: SendGcodeButton
        function SendGcodeButtonPushed(app, event)
            %Send Gcode to Arduino
            gcodeLines = readGCodeFile('gcode_grid.txt');
            APPsendGcode(app,gcodeLines,app.COMM_PORT);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            %Do all functions with just one button
            CaptureButtonPushed(app,event);
            ScanButtonPushed(app,event);
            EncodeButtonPushed(app,event);
            SendGcodeButtonPushed(app,event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorLocatorUIFigure = uifigure;
            app.ColorLocatorUIFigure.Name = 'Color Locator';
            app.CameraURLEditField = uieditfield(app.ColorLocatorUIFigure, 'text');
            app.CameraURLEditField.ValueChangedFcn = createCallbackFcn(app, @CameraURLEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.ColorLocatorUIFigure);
            app.CameraURLEditFieldLabel = uilabel(app.ColorLocatorUIFigure);
            app.CameraURLEditFieldLabel.Text = 'Camera URL';
            app.CaptureButton = uibutton(app.ColorLocatorUIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.ScanButton = uibutton(app.ColorLocatorUIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.EncodeButton = uibutton(app.ColorLocatorUIFigure, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.SendGcodeButton = uibutton(app.ColorLocatorUIFigure, 'push');
            app.SendGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @SendGcodeButtonPushed, true);
            app.SendGcodeButton.Text = 'Send Gcode';
            app.COMMPortEditFieldLabel = uilabel(app.ColorLocatorUIFigure);
            app.COMMPortEditFieldLabel.Text = 'COMM Port';
            app.COMMPortEditField = uieditfield(app.ColorLocatorUIFigure, 'text');
            app.COMMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMMPortEditFieldValueChanged, true);
            app.GcodeSendFinishedLampLabel = uilabel(app.ColorLocatorUIFigure);
            app.GcodeSendFinishedLampLabel.Text = 'Gcode Send Finished';
            app.GcodeSendFinishedLamp = uilamp(app.ColorLocatorUIFigure);
            app.GcodeSendFinishedLamp.Enable = 'off';
            app.EncodedDataTextArea = uitextarea(app.ColorLocatorUIFigure);
            app.EncodedDataTextArea.Editable = 'off';
            app.EncodedDataLabel = uilabel(app.ColorLocatorUIFigure);
            app.EncodedDataLabel.Text = 'Encoded Data';
            app.AutoButton = uibutton(app.ColorLocatorUIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.CameraViewLabel = uilabel(app.ColorLocatorUIFigure);
            app.CameraViewLabel.Text = 'Camera View';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ColorLocator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColorLocatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorLocatorUIFigure)
        end
    end
end